classdef DataLogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UDPDataLoggerUIFigure         matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        LiveDataViewTab               matlab.ui.container.Tab
        SimTimeLabel                  matlab.ui.control.Label
        simTimeTxtBox                 matlab.ui.control.Label
        SteerGauge                    matlab.ui.control.Gauge
        SteerGaugeLabel               matlab.ui.control.Label
        BrakeGauge                    matlab.ui.control.Gauge
        BrakeGaugeLabel               matlab.ui.control.Label
        GasGauge                      matlab.ui.control.Gauge
        GasGaugeLabel                 matlab.ui.control.Label
        SpeedGauge                    matlab.ui.control.Gauge
        SpeedGaugeLabel               matlab.ui.control.Label
        SettingsTab                   matlab.ui.container.Tab
        isUnity                       matlab.ui.control.CheckBox
        DataCollectionSystemSettingsLabel  matlab.ui.control.Label
        maxDuration                   matlab.ui.control.NumericEditField
        MaxDurationMinutesEditFieldLabel  matlab.ui.control.Label
        listeningPort                 matlab.ui.control.NumericEditField
        ListeningPortEditFieldLabel   matlab.ui.control.Label
        listeningIp                   matlab.ui.control.EditField
        ListeningIPLabel              matlab.ui.control.Label
        StopListeningforDataButton    matlab.ui.control.Button
        StartListeningforDataButton   matlab.ui.control.Button
        runId                         matlab.ui.control.EditField
        RunIdEditFieldLabel           matlab.ui.control.Label
        subjectId                     matlab.ui.control.EditField
        SubjectIdEditFieldLabel       matlab.ui.control.Label
        expCode                       matlab.ui.control.EditField
        ExperimentCodeEditFieldLabel  matlab.ui.control.Label
        ExperimentInformationLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)

        uConn = [];
        fName = '';
        emptyContainer = [];
        data = [];
        listenForData = false;

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            try
                app.listeningIp.Value = readSetting('settings/ip.txt');
            catch
                app.listeningIp.Value = '192.168.9.99';
            end

            try
                app.listeningPort.Value = str2double(readSetting('settings/port.txt'));
            catch
                app.listeningPort.Value = 7000;
            end
            
            try
                app.maxDuration.Value = str2double(readSetting('settings/maxduration.txt'));
            catch
                app.maxDuration.Value = 60;
            end            

        end

        % Button pushed function: StartListeningforDataButton
        function StartListeningforDataButtonPushed(app, event)
            
            clc;
            
            app.fName = getFileName(app, {app.expCode.Value, app.subjectId.Value, app.runId.Value});

            % The size of the empty container is determined based on the
            % max minutes of data collection times 60 (because of 60
            % seconds in a minute) and then times 600 again (because of
            % assuming a max rate of 600 observations per second).

            if (app.isUnity.Value)

                app.emptyContainer = single(zeros(app.maxDuration.Value*60*600, 29));

            else

                app.emptyContainer = single(zeros(app.maxDuration.Value*60*600, 23));

            end
            
            ipAdd = app.listeningIp.Value;
            portNo = app.listeningPort.Value;

            if isempty(app.uConn)
                app.uConn = udpport("IPV4", "LocalHost", ipAdd, "LocalPort", portNo);
                flush(app.uConn);
            else
                flush(app.uConn);
            end
            
            app.listenForData = true;
            app.StartListeningforDataButton.Enable = false;
            app.StopListeningforDataButton.Enable = true;
            drawnow;

            collectData(app);
            
        end

        % Button pushed function: StopListeningforDataButton
        function StopListeningforDataButtonPushed(app, event)
            app.listenForData = false;
            app.StartListeningforDataButton.Enable = true;
            app.StopListeningforDataButton.Enable = false;
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UDPDataLoggerUIFigure = uifigure('Visible', 'off');
            app.UDPDataLoggerUIFigure.Name = 'UDP Data Logger';
            app.ExperimentInformationLabel = uilabel(app.UDPDataLoggerUIFigure);
            app.ExperimentInformationLabel.Text = 'Experiment Information';
            app.ExperimentCodeEditFieldLabel = uilabel(app.UDPDataLoggerUIFigure);
            app.ExperimentCodeEditFieldLabel.Text = 'Experiment Code';
            app.expCode = uieditfield(app.UDPDataLoggerUIFigure, 'text');
            app.expCode.Placeholder = 'TEST';
            app.SubjectIdEditFieldLabel = uilabel(app.UDPDataLoggerUIFigure);
            app.SubjectIdEditFieldLabel.Text = 'Subject Id';
            app.subjectId = uieditfield(app.UDPDataLoggerUIFigure, 'text');
            app.subjectId.Placeholder = '001';
            app.RunIdEditFieldLabel = uilabel(app.UDPDataLoggerUIFigure);
            app.RunIdEditFieldLabel.Text = 'Run Id';
            app.runId = uieditfield(app.UDPDataLoggerUIFigure, 'text');
            app.runId.Placeholder = '1';
            app.StartListeningforDataButton = uibutton(app.UDPDataLoggerUIFigure, 'push');
            app.StartListeningforDataButton.ButtonPushedFcn = createCallbackFcn(app, @StartListeningforDataButtonPushed, true);
            app.StartListeningforDataButton.Text = 'Start Listening for Data';
            app.StopListeningforDataButton = uibutton(app.UDPDataLoggerUIFigure, 'push');
            app.StopListeningforDataButton.ButtonPushedFcn = createCallbackFcn(app, @StopListeningforDataButtonPushed, true);
            app.StopListeningforDataButton.Enable = 'off';
            app.StopListeningforDataButton.Text = 'Stop Listening for Data';
            app.TabGroup = uitabgroup(app.UDPDataLoggerUIFigure);
            app.LiveDataViewTab = uitab(app.TabGroup);
            app.LiveDataViewTab.Title = 'Live Data View';
            app.SpeedGaugeLabel = uilabel(app.LiveDataViewTab);
            app.SpeedGaugeLabel.Text = 'Speed';
            app.SpeedGauge = uigauge(app.LiveDataViewTab, 'circular');
            app.GasGaugeLabel = uilabel(app.LiveDataViewTab);
            app.GasGaugeLabel.Text = 'Gas';
            app.GasGauge = uigauge(app.LiveDataViewTab, 'circular');
            app.GasGauge.Limits = [0 90];
            app.GasGauge.MajorTicks = [0 45 90];
            app.BrakeGaugeLabel = uilabel(app.LiveDataViewTab);
            app.BrakeGaugeLabel.Text = 'Brake';
            app.BrakeGauge = uigauge(app.LiveDataViewTab, 'circular');
            app.BrakeGauge.Limits = [0 300];
            app.SteerGaugeLabel = uilabel(app.LiveDataViewTab);
            app.SteerGaugeLabel.Text = 'Steer';
            app.SteerGauge = uigauge(app.LiveDataViewTab, 'circular');
            app.SteerGauge.Limits = [-180 180];
            app.simTimeTxtBox = uilabel(app.LiveDataViewTab);
            app.simTimeTxtBox.Text = '0.00';
            app.SimTimeLabel = uilabel(app.LiveDataViewTab);
            app.SimTimeLabel.Text = 'Sim Time:';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ListeningIPLabel = uilabel(app.SettingsTab);
            app.ListeningIPLabel.Text = 'Listening IP';
            app.listeningIp = uieditfield(app.SettingsTab, 'text');
            app.listeningIp.Value = '192.168.0.100';
            app.ListeningPortEditFieldLabel = uilabel(app.SettingsTab);
            app.ListeningPortEditFieldLabel.Text = 'Listening Port';
            app.listeningPort = uieditfield(app.SettingsTab, 'numeric');
            app.listeningPort.Limits = [80 10000];
            app.listeningPort.ValueDisplayFormat = '%.0f';
            app.listeningPort.Value = 7000;
            app.MaxDurationMinutesEditFieldLabel = uilabel(app.SettingsTab);
            app.MaxDurationMinutesEditFieldLabel.Text = 'Max Duration (Minutes)';
            app.maxDuration = uieditfield(app.SettingsTab, 'numeric');
            app.maxDuration.Limits = [1 120];
            app.maxDuration.Value = 60;
            app.DataCollectionSystemSettingsLabel = uilabel(app.SettingsTab);
            app.DataCollectionSystemSettingsLabel.Text = 'Data Collection System Settings';
            app.isUnity = uicheckbox(app.SettingsTab);
            app.isUnity.Text = 'Listen for Unity Simulator Stream';
            % Show the figure after all components are created
            app.UDPDataLoggerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataLogger

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UDPDataLoggerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UDPDataLoggerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UDPDataLoggerUIFigure)
        end
    end
end